classdef UI_CVSA512 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TopoplotPanel  matlab.ui.container.Panel
        SaveButton     matlab.ui.control.Button
        FischerAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% processing variables
        c_subject        
        selectedFeatures = {}; 
        markerFeatures = {};
        avg = 1;
        filterOrder = 4;
        nchannels = 39;
        windows_size = 512;
        classes = [730 731];
        fix_event = 786;
        cf_event = 781;
        startTrial_event = 786; %% in future check for fake_rest
        nclasses;
        bands = {[8 10] [10 12] [12 14] [14 16] [8 14]};
        % bands = {[ 8 14]};
        bands_str;
        nbands;
        headers;
        channels_select = {'P3', 'PZ', 'P4', 'POZ', 'O1', 'O2', 'P5', 'P1', 'P2', 'P6', 'PO5', 'PO3', 'PO4', 'PO6', 'PO7', 'PO8', 'OZ'};
%         channels_select = {'FP1', 'FP2', 'F3', 'FZ', 'F4', 'FC1', 'FC2', 'C3', 'CZ', 'C4', 'CP1', 'CP2', 'P3', 'PZ', 'P4', 'POZ', 'O1', 'O2', 'EOG', ...
%             'F1', 'F2', 'FC3', 'FCZ', 'FC4', 'C1', 'C2', 'CP3', 'CP4', 'P5', 'P1', 'P2', 'P6', 'PO5', 'PO3', 'PO4', 'PO6', 'PO7', 'PO8', 'OZ'};
        channels_label;
        nselected_channels;
        normalize_std = false;
        normalization_baseline = false;
        chanlocs_path = '/home/paolo/chanlocs39.mat';
        data;
        fischer_matrix;
        idx_channels_select;
        TopoplotImages;
        MarkerHandle;
        filenames;
        pathname;
    end


    methods (Access = private)

        function configure(app)
            %% Configure
            app.UIFigure.Visible = 'off';
            % % initialize variables for data
            app.nclasses = length(app.classes);
            app.nbands = length(app.bands);
            app.headers = cell(1, app.nbands);
            app.data = cell(1, app.nbands);
            app.nselected_channels = size(app.channels_select, 2);
            app.fischer_matrix = nan(app.nbands, app.nchannels);
            app.bands_str = cellfun(@(x) sprintf('%d-%d', x(1), x(2)), app.bands, 'UniformOutput', false);
            app.TopoplotImages = cell(1, app.nbands);
            
            signals = cell(1, app.nbands);
            for idx_band = 1:app.nbands
                app.headers{idx_band}.TYP = [];
                app.headers{idx_band}.DUR = [];
                app.headers{idx_band}.POS = [];
                signals{idx_band} = [];
            end

            % load the gdfs
            [app.filenames, app.pathname] = uigetfile('*.gdf', 'Select GDF Files', 'MultiSelect', 'on');

            % % Concatenate all + processing + extract selected channels
            nFiles = length(app.filenames);
            for idx_file= 1: nFiles
                fullpath_file = fullfile(app.pathname, app.filenames{idx_file});
                disp(['file (' num2str(idx_file) '/' num2str(nFiles)  '): ', app.filenames{idx_file}]);
                [signal,header] = sload(fullpath_file);
                signal = signal(:,1:app.nchannels);
                app.channels_label = header.Label;
                [~, app.idx_channels_select] = ismember(app.channels_select, app.channels_label);

                for idx_band = 1:app.nbands
                    band = app.bands{idx_band};
                    signal_processed = proc_512hz(signal, header.SampleRate, band, app.filterOrder, 1);
                    c_header = app.headers{idx_band};
                    c_header.sampleRate = header.SampleRate;
                    c_header.channels_labels = header.Label;
                    c_header.TYP = cat(1, c_header.TYP, header.EVENT.TYP);
                    c_header.DUR = cat(1, c_header.DUR, header.EVENT.DUR);
                    c_header.POS = cat(1, c_header.POS, header.EVENT.POS + size(signals{idx_band}, 1));

                    signals{idx_band} = cat(1, signals{idx_band}, signal_processed(:,:));
                    app.headers{idx_band} = c_header;
                end
            end


            % % extract all data
            % for each band the signal are: samples x channels x trial
            %   in data the signal keep this division for data.trial, data.cue data.cf data.fix
            for idx_band = 1:app.nbands
                app.data{idx_band} = extract_all(signals{idx_band}, app.headers{idx_band}, app.classes, app.fix_event, app.cf_event, app.startTrial_event);
            end

            app.UIFigure.Visible = 'on';
            
        end

        function fischer(app)
            %% compute fisher
            for idx_band = 1:app.nbands
                app.fischer_matrix(idx_band, :) = compute_fiser(app.classes, app.data{idx_band}.cf, app.data{idx_band}.typ, app.normalize_std);
            end
            app.fischer_matrix = app.fischer_matrix';
            app.fischer_matrix = app.fischer_matrix(app.idx_channels_select,:);

            app.configureFischerAxes()
            app.show_fischer();
        end

        function configureFischerAxes(app)
            % Datacursor mode on
            dcm = datacursormode(app.UIFigure);
            dcm.Enable = 'on';
            dcm.DisplayStyle = 'datatip';
            dcm.UpdateFcn = @(src, event) app.fischerCallback(src, event);
        end

        function show_fischer(app)
            cla(app.FischerAxes);

            imagesc(app.FischerAxes, app.fischer_matrix);
            colormap(app.FischerAxes, 'jet');
            colorbar(app.FischerAxes);
            app.FischerAxes.XTick = 1:app.nbands;
            app.FischerAxes.XTickLabel = app.bands_str;
            app.FischerAxes.YTick = 1:app.nselected_channels;
            app.FischerAxes.YTickLabel = app.headers{1}.channels_labels(app.idx_channels_select);
            app.FischerAxes.XLabel.String = 'Bands';
            app.FischerAxes.YLabel.String = 'Channels';
        end

        % Callback for cell selection
        function txt = fischerCallback(app,~,event)
            % Coords mouse click
            pos = event.Position;
            x = pos(1);
            y = pos(2);

            % Check valid coords
            if x >= 1 && x <= size(app.fischer_matrix, 2) && y >= 1 && y <= size(app.fischer_matrix, 1)
                 % check if it is empty or has elements
                 if isempty(app.selectedFeatures)
                     % Add coords selected
                     app.selectedFeatures = [app.idx_channels_select(y), x];
                     app.markerFeatures = [y, x];
                 elseif any(ismember(app.selectedFeatures, [app.idx_channels_select(y),x], 'rows'))
                     % Remove re-selected cell
                     app.markerFeatures = app.markerFeatures(~ismember(app.markerFeatures, [y, x], 'rows'),:);
                     app.selectedFeatures = app.selectedFeatures(~ismember(app.selectedFeatures, [app.idx_channels_select(y), x], 'rows'), :);
                 else
                     % Add coords selected
                     app.markerFeatures = [app.markerFeatures; y, x];
                     app.selectedFeatures = [app.selectedFeatures; app.idx_channels_select(y), x];

                 end

                 if isvalid(app.FischerAxes)
                     delete(app.MarkerHandle); % Remove previous markers
                     hold(app.FischerAxes, 'on');
                     app.MarkerHandle = scatter(app.FischerAxes, app.markerFeatures(:, 2), app.markerFeatures(:, 1), ...
                         100, 'w', 'LineWidth', 3);
                     hold(app.FischerAxes, 'off');
                 end

                 % Show test
                 txt = {['Band: ', app.bands_str{x}], ...
                     ['Channel: ', app.channels_label{app.idx_channels_select(y)}], ...
                     ['Fischer Score: ', num2str(app.fischer_matrix(y, x))]};
            else
                 txt = '';
            end

        end

        function show_topoplot(app)
            topo = cell(1, app.nbands);
            for idx_band = 1:app.nbands
                if app.normalization_baseline
                    baseline = mean(app.data{1}.fix, 1);
                    topo{idx_band}.cf = app.data{idx_band}.cf ./ repmat(baseline, [size(app.data{idx_band}.cf, 1) 1 1]);
                else
                    topo{idx_band}.cf = app.data{idx_band}.cf;
                end
            end

            % compute the chanlocs data
            chanlocs_data = cell(1, app.nbands);
            cl = -inf;
            for idx_band=1:app.nbands
                data_1 = mean(mean(topo{idx_band}.cf(:,:,app.data{idx_band}.typ == app.classes(1)), 3), 1);
                data_2 = mean(mean(topo{idx_band}.cf(:,:,app.data{idx_band}.typ == app.classes(2)), 3), 1);
                diff = data_2 - data_1;
                tmp_chanlocs_data = zeros(app.nchannels, 1);
                tmp_chanlocs_data(app.idx_channels_select) = diff(app.idx_channels_select);
                chanlocs_data{idx_band} = tmp_chanlocs_data;
                cl = max(cl, max(abs(tmp_chanlocs_data)));
            end

            % compute the figures to show
            load(app.chanlocs_path);
            topo_images = cell(1, app.nbands);
            for idx_band = 1:app.nbands
                fig = figure('Visible', 'off');
                topoplot(squeeze(chanlocs_data{idx_band}), chanlocs, 'headrad', 'rim', 'maplimits', ...
                    [-max(abs(chanlocs_data{idx_band})) max(abs(chanlocs_data{idx_band}))], 'electrodes', 'labelpoint');
                set(gca, 'clim', [-cl cl]);
                colorbar;
                title(['band: ' app.bands_str{idx_band}]);

                drawnow;

                frame = getframe(fig);
                topo_images{idx_band} = frame2im(frame);  % Convert frame to an image
                close(fig);

            end
            panelWidth = app.TopoplotPanel.Position(3);
            panelHeight = app.TopoplotPanel.Position(4);
            numRows = ceil(sqrt(app.nbands)); 
            numCols = ceil(app.nbands/numRows);
            imgWidth = panelWidth / numCols;
            imgHeight = panelHeight / numRows;
            for idx_band=1:app.nbands
                col = mod(idx_band-1, numCols);
                row = floor((idx_band-1) / numCols);

                app.TopoplotImages{idx_band} = uiimage('Parent', app.TopoplotPanel, ...
                'ImageSource', topo_images{idx_band}, ...
                'Position', [col * imgWidth, (numRows-row-1) * imgHeight, imgWidth, imgHeight]);

                drawnow;

            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initializeApp(app)
            addpath('/home/paolo/cvsa_ws/src/analysis_cvsa/512hz/utils')
            addpath('/home/paolo/cvsa_ws/src/analysis_cvsa/utils')
            app.configure();
            app.fischer();
            app.show_topoplot();

        end

        % Callback function
        function SelectButtonPushed(app, event)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             if isempty(app.selectedFeatures)
                uialert(app.UIFigure, 'Noone feature selected', 'Attenzione');
            else
                % Save selected features in a .mat file
                uialert(app.UIFigure, 'Creating dataset...', 'Attenzione');
                [X, y, info] = createDataset(app.filenames, app.data, app.selectedFeatures, app.bands, app.headers{1}.channels_labels);
                sfile = fullfile(app.pathname, '../set/logbandDataset_512hz.mat');
                info.sampleRate = app.headers{1}.sampleRate;
                info.filterOrder = app.filterOrder;
                save(sfile, 'X', 'y', 'info');
                uialert(app.UIFigure, 'Creating dataset......done', 'Attenzione');
             end

             close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FischerAxes = uiaxes(app.UIFigure);
            title(app.FischerAxes, 'Fischer Score')
            xlabel(app.FischerAxes, 'Frequency Bands [Hz]')
            ylabel(app.FischerAxes, 'Channels')
            colormap(app.FischerAxes, 'jet')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TopoplotPanel = uipanel(app.UIFigure);
            app.TopoplotPanel.Title = 'Topoplot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_CVSA512

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initializeApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end