classdef UI_CVSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TopoplotPanel  matlab.ui.container.Panel
        SelectButton   matlab.ui.control.Button
        SaveButton     matlab.ui.control.Button
        FischerAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FischerScores  % Fischer matrix
        RowLabels      % Channels labels
        ColLabels      % Bands label
        Bands          % Bands analyzed
        c_subject         % Subject
        SelectedFeatures  % Features selected 
        SelectedCells  % Celected cells
        SelectedCh     % Channels selected
        LogbandPower   % Logarithmic band power
        ElectrodePos   % Chanlocs
        day            % Day of the experiment
    end


    methods (Access = private)
        
        function loadData(app)
            % Load the current subject
            subj = load("c_subject.mat");
            app.c_subject = subj.c_subject;
            app.day = subj.day;

            % Create paths to directory
            baseDir = ['/home/paolo/cvsa_ws/record/' app.c_subject app.day '/dataset'];
            f_path = fullfile(baseDir,'fischer_scores.mat');
            l_path = fullfile(baseDir,'logband_power.mat');

            % Load fischer scores, logband data and electrodes position
            data = load(f_path);
            app.FischerScores = data.cva_selected;
            app.RowLabels = data.rowLabels;
            app.ColLabels = data.colLabels;
            app.Bands = data.band;
            
            logbandData = load(l_path);
            app.LogbandPower = logbandData.logbandPower;
            app.ElectrodePos = logbandData.electrodePos;
        end

        % Configuration of fischer score
        function configureFischerAxes(app)
            % Show the fischer matrix
            drawFischer(app)

            % Datacursor mode on
            dcm = datacursormode(app.UIFigure);
            dcm.Enable = 'on';
            dcm.DisplayStyle = 'datatip';
            dcm.UpdateFcn = @(src, event) app.ImageClickCallback(src, event);
        end

        function drawFischer(app)
            imagesc(app.FischerAxes, app.FischerScores);
            colormap(app.FischerAxes, 'jet');
            colorbar(app.FischerAxes);
            app.FischerAxes.XTick = 1:length(app.ColLabels);
            app.FischerAxes.XTickLabel = app.ColLabels;
            app.FischerAxes.YTick = 1:length(app.RowLabels);
            app.FischerAxes.YTickLabel = app.RowLabels;
            app.FischerAxes.XLabel.String = 'Bands';
            app.FischerAxes.YLabel.String = 'Channels';
        end

        % Configure the topoplots
        function configureTopoplotSubplots(app)
            numBands = size(app.LogbandPower, 2);
            numRows = ceil(sqrt(numBands)); 
            numCols = ceil(numBands/numRows); 
            % Dimension and margin for subplots
            axWidth = 1/numCols;
            axHeight = 1/numRows;
            for i = 1:numBands
                row = floor((i-1)/numCols);
                col = mod(i-1, numCols);

                % Compute the position of the axis
                axPos = [col * axWidth, (numRows - row - 1) * axHeight, axWidth, axHeight];

                % Build tmp figure for the subplot
                tempFig = figure('Visible', 'off');
                topoplot(squeeze(app.LogbandPower(:, i)), app.ElectrodePos, 'style', 'both', 'electrodes', 'labelpoint','headrad','rim','maplimits',[-max(abs(app.LogbandPower(:,i))) max(abs(app.LogbandPower(:,i)))]);
                frame = getframe(tempFig);
                img = frame2im(frame);
                close(tempFig);

                ax = axes('Parent', app.TopoplotPanel, 'Position', axPos);
                imshow(img, 'Parent', ax);
                colorbar(ax)
                title(ax,app.ColLabels{i});
            end
        end

        
        % Callback for cell selection
        function txt = ImageClickCallback(app,~,event)
            % Coords mouse click
            pos = event.Position;
            x = pos(1);
            y = pos(2);

            % Check valid coords
            if x >= 1 && x <= size(app.FischerScores, 2) && y >= 1 && y <= size(app.FischerScores, 1)
                 % Check if cell already selected
                if ~isempty(app.SelectedCells) && any(ismember(app.SelectedCells, [y, x], 'rows'))
                    % Remove re-selected cell
                    app.SelectedCells = app.SelectedCells(~ismember(app.SelectedCells, [y, x], 'rows'), :);

                    % Replot the image
                    drawFischer(app)

                    % Replot remaining markers
                    hold(app.FischerAxes, 'on');
                    scatter(app.FischerAxes, app.SelectedCells(:, 2), app.SelectedCells(:, 1), 100, 'w', 'LineWidth',3);
                    hold(app.FischerAxes, 'off');
                else
                    % Add coords selected
                    if isempty(app.SelectedCells)
                        app.SelectedCells = [y, x];
                    else
                        app.SelectedCells = [app.SelectedCells; y, x];
                    end

                    % Update image
                    hold(app.FischerAxes, 'on');
                    scatter(app.FischerAxes, x, y, 100, 'w', 'LineWidth',3);
                    hold(app.FischerAxes, 'off');
                end
                
                % Show test
                txt = {['Band: ', app.ColLabels{x}], ...
                       ['Channel: ', app.RowLabels{y}], ...
                       ['Fischer Score: ', num2str(app.FischerScores(y, x))]};
            else
                txt = '';
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initializeApp(app)
            % Load data
            app.loadData();
            % CConfigure fischer
            app.configureFischerAxes();
             % Configure topoplot
            app.configureTopoplotSubplots();
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
             % Take selected cells
            selectedCells = app.SelectedCells;

            if isempty(selectedCells)
                uialert(app.UIFigure, 'Noone cell selected', 'Attenzione');
                return;
            end

            % Extract cell band and channel 
            app.SelectedFeatures = cell(size(selectedCells, 1), 3);
            for i = 1:size(selectedCells, 1)
                row = selectedCells(i, 1);
                col = selectedCells(i, 2);
                app.SelectedFeatures{i, 1} = app.RowLabels{row};
                app.SelectedFeatures{i, 2} = app.Bands{col};
                app.SelectedFeatures{i, 3} = app.FischerScores(row, col);
            end

            % Show selected features
            disp('Selected features:');
            disp(app.SelectedFeatures);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             if isempty(app.SelectedFeatures)
                uialert(app.UIFigure, 'Noone feature selected', 'Attenzione');
            else
                % Save selected features in a .mat file
                s_path = ['/home/paolo/cvsa_ws/record/' app.c_subject app.day '/dataset/selected_features.mat'];
                selectedFeatures = app.SelectedFeatures; 
                save(s_path, 'selectedFeatures', '-mat');
                uialert(app.UIFigure, 'Features saved with success', 'Success');
                createDataset(app.c_subject, app.day)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FischerAxes = uiaxes(app.UIFigure);
            title(app.FischerAxes, 'Fischer Score')
            xlabel(app.FischerAxes, 'Frequency Bands [Hz]')
            ylabel(app.FischerAxes, 'Channels')
            colormap(app.FischerAxes, 'jet')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TopoplotPanel = uipanel(app.UIFigure);
            app.TopoplotPanel.Title = 'TopoplotPanel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_CVSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initializeApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end